classdef DiameterCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        lblDescription  matlab.ui.control.Label
        MaxSizeVox      matlab.ui.control.NumericEditField
        LargestobjectsizevoxelsEditFieldLabel  matlab.ui.control.Label
        MinSizeVox      matlab.ui.control.NumericEditField
        SmallestobjectsizevoxelsEditFieldLabel  matlab.ui.control.Label
        btnCalculate    matlab.ui.control.Button
    end


    properties (Access = private)
        Owner = []; % Application calling this dialog
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Owner)
            app.Owner = Owner;
            
            % Center the dialog on main window
            pos = app.UIFigure.Position;
            posParent = app.Owner.UIFigure.Position;
            pos(1) = ceil(posParent(1)+posParent(3)/2-pos(3)/2);
            pos(2) = ceil(posParent(2)+posParent(4)/2-pos(4)/2);
            app.UIFigure.Position = pos;             
        end

        % Button pushed function: btnCalculate
        function btnCalculateButtonPushed(app, event)
            app.Owner.MinSizeXY.Value = app.Owner.convertVoxelsToDiameter(app.MinSizeVox.Value);
            app.Owner.MaxSizeXY.Value = app.Owner.convertVoxelsToDiameter(app.MaxSizeVox.Value);
            app.UIFigure.Visible = 'off';
            app.Owner.UIFigure.Visible = 'off';
            app.Owner.UIFigure.Visible = 'on';                        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Calculator';
            app.btnCalculate = uibutton(app.UIFigure, 'push');
            app.btnCalculate.ButtonPushedFcn = createCallbackFcn(app, @btnCalculateButtonPushed, true);
            app.btnCalculate.Icon = '48px-calculator.png';
            app.btnCalculate.Text = {'Calculate'; 'diameter'};
            app.SmallestobjectsizevoxelsEditFieldLabel = uilabel(app.UIFigure);
            app.SmallestobjectsizevoxelsEditFieldLabel.Text = 'Smallest object size (voxels):';
            app.MinSizeVox = uieditfield(app.UIFigure, 'numeric');
            app.MinSizeVox.Limits = [1 Inf];
            app.MinSizeVox.ValueDisplayFormat = '%.0f';
            app.MinSizeVox.Value = 1;
            app.LargestobjectsizevoxelsEditFieldLabel = uilabel(app.UIFigure);
            app.LargestobjectsizevoxelsEditFieldLabel.Text = 'Largest object size (voxels):';
            app.MaxSizeVox = uieditfield(app.UIFigure, 'numeric');
            app.MaxSizeVox.Limits = [1 Inf];
            app.MaxSizeVox.ValueDisplayFormat = '%.0f';
            app.MaxSizeVox.Value = 1;
            app.lblDescription = uilabel(app.UIFigure);
            app.lblDescription.Text = {'Convert the expected size range of objects'; 'from voxels to diameter of the equivalent sphere'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DiameterCalculator(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end