classdef NewExperiment < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        btnCreate        matlab.ui.control.Button
        txtFolder        matlab.ui.control.EditField
        btnSelectFolder  matlab.ui.control.Button
        btnCancel        matlab.ui.control.Button
        lstImages        matlab.ui.control.ListBox
        btnImageAdd      matlab.ui.control.Button
        btnImageRemove   matlab.ui.control.Button
        btnImageClear    matlab.ui.control.Button
        lblImages        matlab.ui.control.Label
        lstSkels         matlab.ui.control.ListBox
        btnSkelAdd       matlab.ui.control.Button
        btnSkelRemove    matlab.ui.control.Button
        btnSkelClear     matlab.ui.control.Button
        lblSkel          matlab.ui.control.Label
    end


    properties (Access = private)
        Owner   = [];    % Application calling this dialog
        LastFolder = []; % Last folder path used to import files
    end

    properties (Access = public)
        Folder  = [];   % Home folder for the new experiment
    end

    methods (Access = private)
    
        function result = canCreateExperiment(app)
            % Checks if all conditions to create a new experiment are met
            result = ~isempty(app.txtFolder.Value) && isfolder(app.txtFolder.Value) && ~isempty(app.lstImages.Items);
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Owner)
            app.Owner = Owner;
            
            % Center the dialog on main window
            pos = app.UIFigure.Position;
            posParent = app.Owner.UIFigure.Position;
            pos(1) = ceil(posParent(1)+posParent(3)/2-pos(3)/2);
            pos(2) = ceil(posParent(2)+posParent(4)/2-pos(4)/2);
            app.UIFigure.Position = pos;             
        end

        % Button pushed function: btnCreate
        function btnCreateButtonPushed(app, event)
            dlg = uiprogressdlg(app.UIFigure,'Title','Creating new Project...','Indeterminate', 'on');            

            % Create experiment folder structure and copy images into I folder                   
            cd(app.txtFolder.Value);
            if ~isfolder([pwd filesep 'I']), mkdir([pwd filesep 'I']); end
            if ~isfolder([pwd filesep 'objects']), mkdir([pwd filesep 'objects']); end
            if ~isfolder([pwd filesep 'simulations']), mkdir([pwd filesep 'simulations']); end
            if ~isfolder([pwd filesep 'skeletons']), mkdir([pwd filesep 'skeletons']); end
            if ~isfolder([pwd filesep 'results']), mkdir([pwd filesep 'results']); end

            % Import images
            for i = 1:numel(app.lstImages.ItemsData)
                [~, fName, fExt] = fileparts(app.lstImages.ItemsData{i});
                dlg.Message = ['Copying image: ' fName fExt];
                copyfile(app.lstImages.ItemsData{i}, [pwd filesep 'I' filesep fName fExt]);
            end

            % Import skeletons
            for i = 1:numel(app.lstSkels.ItemsData)
                [fPath, fName, fExt] = fileparts(app.lstSkels.ItemsData{i});
                dlg.Message = ['Importing skeleton: ' fName fExt];
                switch fExt
                    case '.mat'
                        copyfile(app.lstSkels.ItemsData{i}, [pwd filesep 'skeletons' filesep fName fExt]);
                    case '.traces'
                        Skel = convertSkelSNT2ml(fPath, [fName fExt]);
                        dlg.Message = 'Calculating distance to soma...';
                        Skel = calcSkelDistToSoma(Skel);
                        Skel.Name = fName;
                        saveSkel(Skel);                       
                end
            end
            
            dlg.Message = 'Getting things ready...';
            app.Owner.RefreshHomeFolder(false, true);  
            close(dlg);
            app.UIFigure.Visible = 'off';            
            app.Owner.UIFigure.Visible = 'off';
            app.Owner.UIFigure.Visible = 'on';            
        end

        % Button pushed function: btnCancel
        function btnCancelButtonPushed(app, event)
            app.UIFigure.Visible = 'off';
            app.Owner.UIFigure.Visible = 'off';
            app.Owner.UIFigure.Visible = 'on';                        
        end

        % Button pushed function: btnImageRemove
        function btnImageRemoveButtonPushed(app, event)
            switch uiconfirm(app.UIFigure, 'Remove selected item from list?','Remove item','Options', {'No', 'Yes'}, 'DefaultOption',1,'CancelOption',1)
                case 'Yes'
                    idx = find(strcmp(app.lstImages.ItemsData, app.lstImages.Value));
                    app.lstImages.ItemsData(idx) = [];
                    app.lstImages.Items(idx) = [];
                    app.lstImagesValueChanged;
            end
            app.btnCreate.Enable = app.canCreateExperiment;
        end

        % Button pushed function: btnImageClear
        function btnImageClearButtonPushed(app, event)
            switch uiconfirm(app.UIFigure, 'Remove all images from list?','Remove items','Options', {'No', 'Yes'}, 'DefaultOption',1,'CancelOption',1)
                case 'Yes'                    
                    app.lstImages.Items = {};
                    app.lstImages.ItemsData = {};
                    app.lstImagesValueChanged;
            end            
            app.btnCreate.Enable = app.canCreateExperiment;
        end

        % Button pushed function: btnImageAdd
        function btnImageAddButtonPushed(app, event)
            if isempty(app.LastFolder)
                fPath = pwd;
            else
                fPath = app.LastFolder;
            end
            [fNames, fPath] = uigetfile([fPath filesep '*.tif'], 'Select images to import', 'MultiSelect', 'on');
            app.UIFigure.Visible = 'off'; % Fixes glitch by which app loses focus
            app.UIFigure.Visible = 'on';  % Fixes glitch by which app loses focus
            
            % Check whether user cancelled the operation
            if iscell(fNames) 
                if isempty(fNames) 
                    app.btnCreate.Enable = app.canCreateExperiment;
                    return
                end
            elseif fNames == 0
                app.btnCreate.Enable = app.canCreateExperiment;
                return
            end
            app.LastFolder = fPath;
            
            % Add image files to list
            if ~iscell(fNames)
                    if isempty(app.lstImages.Items)
                        app.lstImages.Items     = {fNames};
                        app.lstImages.ItemsData = {fullfile(fPath, fNames)};
                    else
                        app.lstImages.Items{end+1}      = fNames;
                        app.lstImages.ItemsData{end+1}  = fullfile(fPath, fNames);
                    end
            else
                for i = 1:numel(fNames)
                    if isempty(app.lstImages.Items)
                        app.lstImages.Items     = {fNames{i}};
                        app.lstImages.ItemsData = {fullfile(fPath, fNames{i})};
                    else
                        app.lstImages.Items{end+1}      = fNames{i};
                        app.lstImages.ItemsData{end+1}  = fullfile(fPath, fNames{i});
                    end
                end
            end
            
            app.btnImageClear.Enable = 'on';
            app.btnCreate.Enable = app.canCreateExperiment;
        end

        % Button pushed function: btnSelectFolder
        function btnSelectFolderButtonPushed(app, event)
            fPath = uigetdir(pwd, 'Select or create an empty folder');
            app.UIFigure.Visible = 'off'; % Fixes glitch by which app loses focus
            app.UIFigure.Visible = 'on';  % Fixes glitch by which app loses focus
            
            if fPath
                if ~isfolder(fPath)
                    app.btnCreate.Enable = app.canCreateExperiment;
                    return
                end
                app.txtFolder.Value = fPath;                
            end            
            app.btnCreate.Enable = app.canCreateExperiment;            
        end

        % Value changed function: lstImages
        function lstImagesValueChanged(app, event)
            if isempty(app.lstImages.Items)
                app.btnImageRemove.Enable  = 'off';
                app.btnImageClear.Enable   = 'off';
            else
                app.btnImageRemove.Enable  = 'on';
                app.btnImageClear.Enable   = 'on';
            end
        end

        % Button pushed function: btnSkelAdd
        function btnSkelAddButtonPushed(app, event)
            if isempty(app.LastFolder)
                fPath = pwd;
            else
                fPath = app.LastFolder;
            end
            [fNames, fPath] = uigetfile(fullfile(fPath, '*.traces; *.mat'), 'Select skeletons', 'MultiSelect', 'on');
            app.UIFigure.Visible = 'off'; % Fixes glitch by which app loses focus
            app.UIFigure.Visible = 'on';  % Fixes glitch by which app loses focus
            
            % Check whether user cancelled the operation
            if iscell(fNames) 
                if isempty(fNames) 
                    app.btnCreate.Enable = app.canCreateExperiment;
                    return
                end
            elseif fNames == 0
                app.btnCreate.Enable = app.canCreateExperiment;
                return
            end
            app.LastFolder = fPath;            
            
            % Add image files to list
            if ~iscell(fNames)
                    if isempty(app.lstImages.Items)
                        app.lstSkels.Items     = {fNames};
                        app.lstSkels.ItemsData = {fullfile(fPath, fNames)};
                    else
                        app.lstSkels.Items{end+1}      = fNames;
                        app.lstSkels.ItemsData{end+1}  = fullfile(fPath, fNames);
                    end
            else
                for i = 1:numel(fNames)
                    if isempty(app.lstImages.Items)
                        app.lstSkels.Items     = {fNames{i}};
                        app.lstSkels.ItemsData = {fullfile(fPath, fNames{i})};
                    else
                        app.lstSkels.Items{end+1}      = fNames{i};
                        app.lstSkels.ItemsData{end+1}  = fullfile(fPath, fNames{i});
                    end
                end
            end
            
            app.btnSkelClear.Enable = 'on';
            app.btnCreate.Enable = app.canCreateExperiment;            
        end

        % Button pushed function: btnSkelRemove
        function btnSkelRemoveButtonPushed(app, event)
            switch uiconfirm(app.UIFigure, 'Remove selected item from list?','Remove item','Options', {'No', 'Yes'}, 'DefaultOption',1,'CancelOption',1)
                case 'Yes'
                    idx = find(strcmp(app.lstSkels.ItemsData, app.lstSkels.Value));
                    app.lstSkels.ItemsData(idx) = [];
                    app.lstSkels.Items(idx) = [];
                    app.lstSkelsValueChanged;
            end
            app.btnCreate.Enable = app.canCreateExperiment;            
        end

        % Value changed function: lstSkels
        function lstSkelsValueChanged(app, event)
            if isempty(app.lstSkels.Items)
                app.btnSkelRemove.Enable  = 'off';
                app.btnSkelClear.Enable   = 'off';
            else
                app.btnSkelRemove.Enable  = 'on';
                app.btnSkelClear.Enable   = 'on';
            end
        end

        % Button pushed function: btnSkelClear
        function btnSkelClearButtonPushed(app, event)
            switch uiconfirm(app.UIFigure, 'Remove all skeletons from list?','Remove items','Options', {'No', 'Yes'}, 'DefaultOption',1,'CancelOption',1)
                case 'Yes'                    
                    app.lstSkels.Items = {};
                    app.lstSkels.ItemsData = {};
                    app.lstSkelsValueChanged;
            end            
            app.btnCreate.Enable = app.canCreateExperiment;            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'New Experiment';
            app.btnCreate = uibutton(app.UIFigure, 'push');
            app.btnCreate.ButtonPushedFcn = createCallbackFcn(app, @btnCreateButtonPushed, true);
            app.btnCreate.Icon = '48px-new.png';
            app.btnCreate.Enable = 'off';
            app.btnCreate.Text = 'Create';
            app.txtFolder = uieditfield(app.UIFigure, 'text');
            app.txtFolder.Editable = 'off';
            app.txtFolder.Value = 'Create or choose an empty folder for the new project     ---->';
            app.btnSelectFolder = uibutton(app.UIFigure, 'push');
            app.btnSelectFolder.ButtonPushedFcn = createCallbackFcn(app, @btnSelectFolderButtonPushed, true);
            app.btnSelectFolder.Icon = '48px-Add-Folder.png';
            app.btnSelectFolder.Tooltip = {'Choose or create an empty folder'};
            app.btnSelectFolder.Text = '';
            app.btnCancel = uibutton(app.UIFigure, 'push');
            app.btnCancel.ButtonPushedFcn = createCallbackFcn(app, @btnCancelButtonPushed, true);
            app.btnCancel.Icon = '48px-Cancel.png';
            app.btnCancel.Text = 'Cancel';
            app.lstImages = uilistbox(app.UIFigure);
            app.lstImages.Items = {};
            app.lstImages.ValueChangedFcn = createCallbackFcn(app, @lstImagesValueChanged, true);
            app.lstImages.Value = {};
            app.btnImageAdd = uibutton(app.UIFigure, 'push');
            app.btnImageAdd.ButtonPushedFcn = createCallbackFcn(app, @btnImageAddButtonPushed, true);
            app.btnImageAdd.Icon = '48px-List-add.png';
            app.btnImageAdd.Tooltip = {'Add an image to the list'};
            app.btnImageAdd.Text = '';
            app.btnImageRemove = uibutton(app.UIFigure, 'push');
            app.btnImageRemove.ButtonPushedFcn = createCallbackFcn(app, @btnImageRemoveButtonPushed, true);
            app.btnImageRemove.Icon = '48px-List-remove.png';
            app.btnImageRemove.Enable = 'off';
            app.btnImageRemove.Tooltip = {'Remove selected item from list'};
            app.btnImageRemove.Text = '';
            app.btnImageClear = uibutton(app.UIFigure, 'push');
            app.btnImageClear.ButtonPushedFcn = createCallbackFcn(app, @btnImageClearButtonPushed, true);
            app.btnImageClear.Icon = '48px-Edit-clear.png';
            app.btnImageClear.Enable = 'off';
            app.btnImageClear.Tooltip = {'Clear list'};
            app.btnImageClear.Text = '';
            app.lblImages = uilabel(app.UIFigure);
            app.lblImages.Text = 'Raw Images and binary masks to analyze';
            app.lstSkels = uilistbox(app.UIFigure);
            app.lstSkels.Items = {};
            app.lstSkels.ValueChangedFcn = createCallbackFcn(app, @lstSkelsValueChanged, true);
            app.lstSkels.Value = {};
            app.btnSkelAdd = uibutton(app.UIFigure, 'push');
            app.btnSkelAdd.ButtonPushedFcn = createCallbackFcn(app, @btnSkelAddButtonPushed, true);
            app.btnSkelAdd.Icon = '48px-List-add.png';
            app.btnSkelAdd.Tooltip = {'Add a skeleton to the list'};
            app.btnSkelAdd.Text = '';
            app.btnSkelRemove = uibutton(app.UIFigure, 'push');
            app.btnSkelRemove.ButtonPushedFcn = createCallbackFcn(app, @btnSkelRemoveButtonPushed, true);
            app.btnSkelRemove.Icon = '48px-List-remove.png';
            app.btnSkelRemove.Enable = 'off';
            app.btnSkelRemove.Tooltip = {'Remove selected item from list'};
            app.btnSkelRemove.Text = '';
            app.btnSkelClear = uibutton(app.UIFigure, 'push');
            app.btnSkelClear.ButtonPushedFcn = createCallbackFcn(app, @btnSkelClearButtonPushed, true);
            app.btnSkelClear.Icon = '48px-Edit-clear.png';
            app.btnSkelClear.Enable = 'off';
            app.btnSkelClear.Tooltip = {'Clear list'};
            app.btnSkelClear.Text = '';
            app.lblSkel = uilabel(app.UIFigure);
            app.lblSkel.Text = 'Add skeletons to analyze';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NewExperiment(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end