classdef SimpleSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        cmbMaxSizeKnown    matlab.ui.control.Switch
        DoyouknowthesizeofbiggestexpectedobjectLabel  matlab.ui.control.Label
        cmbMinSizeKnown    matlab.ui.control.Switch
        DoyouknowthesizeofsmallestexpectedobjectLabel  matlab.ui.control.Label
        cmbSingleZplane    matlab.ui.control.Switch
        IseveryobjectspanningthroughmultipleZplanesLabel  matlab.ui.control.Label
        cmbLocalNoise      matlab.ui.control.Switch
        DoyouwanttoestimatenoiselocallyintheimageLabel  matlab.ui.control.Label
        cmbNoiseEstimator  matlab.ui.control.Switch
        IsnoisethemostcommonintensityinthsimageLabel  matlab.ui.control.Label
        cmbWatershed       matlab.ui.control.Switch
        SeparateobjectspariallyoverlappingeachotherLabel  matlab.ui.control.Label
        lblDescription     matlab.ui.control.Label
        MaxSizeVox         matlab.ui.control.NumericEditField
        LargestexpectedobjectsizevoxelsLabel  matlab.ui.control.Label
        MinSizeVox         matlab.ui.control.NumericEditField
        SmallestecpectedobjectsizevoxelsLabel  matlab.ui.control.Label
        btnApplySettings   matlab.ui.control.Button
    end


    properties (Access = private)
        Owner = []; % Application calling this dialog
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Owner)
            app.Owner = Owner;
            
            % Center the dialog on main window
            pos = app.UIFigure.Position;
            posParent = app.Owner.UIFigure.Position;
            pos(1) = ceil(posParent(1)+posParent(3)/2-pos(3)/2);
            pos(2) = ceil(posParent(2)+posParent(4)/2-pos(4)/2);
            app.UIFigure.Position = pos;           
            
        end

        % Button pushed function: btnApplySettings
        function btnApplySettingsButtonPushed(app, event)
            % Modify main ObjectFinder settings depending to answers given by user
            
            app.Owner.MinSizeXY.Value = app.Owner.convertVoxelsToDiameter(app.MinSizeVox.Value);
            app.Owner.MaxSizeXY.Value = app.Owner.convertVoxelsToDiameter(app.MaxSizeVox.Value);
            switch app.cmbWatershed.Value
                case 'Yes', app.Owner.Watershed.Value = true;
                case 'No' , app.Owner.Watershed.Value = false;
            end
            switch app.cmbNoiseEstimator.Value
                case 'Yes', app.Owner.cmbNoiseEstimator.Value = 'Most common intensity';
                case 'No' , app.Owner.cmbNoiseEstimator.Value = 'Standard deviation';
            end
            switch app.cmbLocalNoise.Value
                case 'Yes', app.Owner.LocalNoise.Value = true;
                case 'No' , app.Owner.LocalNoise.Value = false;
            end
            switch app.cmbSingleZplane.Value
                case 'Yes', app.Owner.SingleZDotCut.Value = true;
                case 'No' , app.Owner.SingleZDotCut.Value = false;
            end            
            
            app.UIFigure.Visible = 'off';
            app.Owner.UIFigure.Visible = 'off';
            app.Owner.UIFigure.Visible = 'on';                        
        end

        % Value changed function: cmbMinSizeKnown
        function cmbMinSizeKnownValueChanged(app, event)
            switch app.cmbMinSizeKnown.Value
                case 'Yes'
                    app.MinSizeVox.Enable = 'on';
                case 'No'
                    app.MinSizeVox.Value = 1;
                    app.MinSizeVox.Enable = 'off';
            end            
        end

        % Value changed function: cmbMaxSizeKnown
        function cmbMaxSizeKnownValueChanged(app, event)
            switch app.cmbMaxSizeKnown.Value
                case 'Yes'
                    app.MaxSizeVox.Enable = 'on';
                case 'No'
                    app.MaxSizeVox.Value = Inf;
                    app.MaxSizeVox.Enable = 'off';
            end                                        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Search settings';
            app.btnApplySettings = uibutton(app.UIFigure, 'push');
            app.btnApplySettings.ButtonPushedFcn = createCallbackFcn(app, @btnApplySettingsButtonPushed, true);
            app.btnApplySettings.Icon = '48px-settings.png';
            app.btnApplySettings.Text = 'Apply settings';
            app.SmallestecpectedobjectsizevoxelsLabel = uilabel(app.UIFigure);
            app.SmallestecpectedobjectsizevoxelsLabel.Text = 'Smallest ecpected object size (# voxels):';
            app.MinSizeVox = uieditfield(app.UIFigure, 'numeric');
            app.MinSizeVox.Limits = [1 Inf];
            app.MinSizeVox.ValueDisplayFormat = '%.0f';
            app.MinSizeVox.Value = 1;
            app.LargestexpectedobjectsizevoxelsLabel = uilabel(app.UIFigure);
            app.LargestexpectedobjectsizevoxelsLabel.Text = 'Largest expected object size (# voxels):';
            app.MaxSizeVox = uieditfield(app.UIFigure, 'numeric');
            app.MaxSizeVox.Limits = [1 Inf];
            app.MaxSizeVox.ValueDisplayFormat = '%.0f';
            app.MaxSizeVox.Value = 1;
            app.lblDescription = uilabel(app.UIFigure);
            app.lblDescription.Text = {'Please answer the following questions about objects '; 'that you expect to find in the image'};
            app.SeparateobjectspariallyoverlappingeachotherLabel = uilabel(app.UIFigure);
            app.SeparateobjectspariallyoverlappingeachotherLabel.Text = 'Separate objects parially overlapping each other?';
            app.cmbWatershed = uiswitch(app.UIFigure, 'slider');
            app.cmbWatershed.Items = {'No', 'Yes'};
            app.cmbWatershed.Value = 'Yes';
            app.IsnoisethemostcommonintensityinthsimageLabel = uilabel(app.UIFigure);
            app.IsnoisethemostcommonintensityinthsimageLabel.Text = 'Is noise the most common intensity in ths image?';
            app.cmbNoiseEstimator = uiswitch(app.UIFigure, 'slider');
            app.cmbNoiseEstimator.Items = {'No', 'Yes'};
            app.cmbNoiseEstimator.Value = 'Yes';
            app.DoyouwanttoestimatenoiselocallyintheimageLabel = uilabel(app.UIFigure);
            app.DoyouwanttoestimatenoiselocallyintheimageLabel.Text = 'Do you want to estimate noise locally in the image?';
            app.cmbLocalNoise = uiswitch(app.UIFigure, 'slider');
            app.cmbLocalNoise.Items = {'No', 'Yes'};
            app.cmbLocalNoise.Value = 'Yes';
            app.IseveryobjectspanningthroughmultipleZplanesLabel = uilabel(app.UIFigure);
            app.IseveryobjectspanningthroughmultipleZplanesLabel.Text = 'Is every object spanning through multiple Z planes?';
            app.cmbSingleZplane = uiswitch(app.UIFigure, 'slider');
            app.cmbSingleZplane.Items = {'No', 'Yes'};
            app.cmbSingleZplane.Value = 'Yes';
            app.DoyouknowthesizeofsmallestexpectedobjectLabel = uilabel(app.UIFigure);
            app.DoyouknowthesizeofsmallestexpectedobjectLabel.Text = 'Do you know the size of smallest expected object?';
            app.cmbMinSizeKnown = uiswitch(app.UIFigure, 'slider');
            app.cmbMinSizeKnown.Items = {'No', 'Yes'};
            app.cmbMinSizeKnown.ValueChangedFcn = createCallbackFcn(app, @cmbMinSizeKnownValueChanged, true);
            app.cmbMinSizeKnown.Value = 'Yes';
            app.DoyouknowthesizeofbiggestexpectedobjectLabel = uilabel(app.UIFigure);
            app.DoyouknowthesizeofbiggestexpectedobjectLabel.Text = 'Do you know the size of biggest expected object?';
            app.cmbMaxSizeKnown = uiswitch(app.UIFigure, 'slider');
            app.cmbMaxSizeKnown.Items = {'No', 'Yes'};
            app.cmbMaxSizeKnown.ValueChangedFcn = createCallbackFcn(app, @cmbMaxSizeKnownValueChanged, true);
            app.cmbMaxSizeKnown.Value = 'Yes';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SimpleSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end