classdef SlicedVolumeInspector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        GridLayout2              matlab.ui.container.GridLayout
        NavigationPanel          matlab.ui.container.Panel
        cmbCMapSelected          matlab.ui.control.DropDown
        SelectedobjectsLabel     matlab.ui.control.Label
        cmbCMapRejected          matlab.ui.control.DropDown
        RejectedobjectsLabel     matlab.ui.control.Label
        cmbCMapValid             matlab.ui.control.DropDown
        ValidobjectsLabel        matlab.ui.control.Label
        btnLeft                  matlab.ui.control.Button
        btnMoveDown              matlab.ui.control.Button
        btnMoveUp                matlab.ui.control.Button
        btnMoveRight             matlab.ui.control.Button
        btnMoveCenter            matlab.ui.control.Button
        cmbNaviStyle             matlab.ui.control.Switch
        cmbCMapStack             matlab.ui.control.DropDown
        RawimageLabel            matlab.ui.control.Label
        btnZoomFit               matlab.ui.control.Button
        btnZoomOut               matlab.ui.control.Button
        btnZoomIn                matlab.ui.control.Button
        AxesNavi                 matlab.ui.control.UIAxes
        pnlTools                 matlab.ui.container.Panel
        btnClear                 matlab.ui.control.Button
        btnFilterRemove          matlab.ui.control.Button
        btnFilterAdd             matlab.ui.control.Button
        UITable                  matlab.ui.control.Table
        ThresholdSpinner         matlab.ui.control.Spinner
        ThresholdLabel           matlab.ui.control.Label
        cmbFilterType            matlab.ui.control.DropDown
        FilterLabel              matlab.ui.control.Label
        btnToggleValidation      matlab.ui.control.Button
        btnReject                matlab.ui.control.Button
        btnValidate              matlab.ui.control.Button
        ReloadimagevolumeButton  matlab.ui.control.Button
        SliderZ                  matlab.ui.control.Slider
        Label                    matlab.ui.control.Label
        AxesZoom                 matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Stack   = []; % Image Volume
        Dots    = []; % Current Objects
        Filter  = []; % Current Filter
    end
    
    properties (Access = private)
        ZoomRect = [0 0 0 0];
        ZoomRectHandle % Description
        SelObjIDs = 0; % Selected object ID
    end
    
    methods (Access = private)
        
        function refreshFrame(app)
            %disp(['Refreshing frame ' num2str(PosZ)]);            
            PosZ = round(app.SliderZ.Value);
            RGB = app.getFrame(PosZ, app.Filter.passF, app.SelObjIDs, [-1 -1]);
            image(app.AxesZoom, RGB);
            xlim(app.AxesZoom, [1 app.ZoomRect(3)]);
            ylim(app.AxesZoom, [1 app.ZoomRect(4)]);
            
            switch app.cmbNaviStyle.Value
                case 'Slice', app.cmbNaviStyleValueChanged;
            end
        end
        
        function initializeImageStack(app)
            PosZ = round(app.SliderZ.Value);
            app.ZoomRect = [1 1 app.Stack.Isize(1) app.Stack.Isize(2)];
            RGB = app.getFrame(PosZ, app.Filter.passF, app.SelObjIDs, [-1 -1]);
            image(app.AxesZoom, RGB);
            colormap(app.AxesZoom, 'gray');
            xlim(app.AxesZoom, [1 size(app.Stack.I,1)]);
            ylim(app.AxesZoom, [1 size(app.Stack.I,2)]);  
            
            switch app.cmbNaviStyle.Value
                case 'MIP'
                    image(app.AxesNavi, app.Stack.MIP);
                    colormap(app.AxesNavi, 'gray');
                case 'Slice'
                    image(app.AxesNavi, RGB);
                    colormap(app.AxesNavi, 'gray');
            end
            xlim(app.AxesNavi, [1 size(app.Stack.I,1)]);
            ylim(app.AxesNavi, [1 size(app.Stack.I,2)]); 
            
            % Disable interaction with volume viewers by default
            disableDefaultInteractivity(app.AxesZoom); 
            disableDefaultInteractivity(app.AxesNavi);            
        end
        
        function [Widget, Pos] = whereIsMouse(app)
            % Locates and reports mouse pointer location
            % Widget = MainWindow | AxesZoom | AxesNavi
            % Pos = [X,Y] coordinates in app.Stack.I pixels
            
            set(app.UIFigure, 'Units', 'pixels');
            PosZoom = get(app.AxesZoom, 'CurrentPoint');
            PosNavi = get(app.AxesNavi, 'CurrentPoint');
            
            Widget = 'MainWindow';
            Pos = [];
            
            if isempty(app.Stack) || isempty(app.Stack.I)
                return
            end
            
            if PosNavi(1,1)>0 && PosNavi(1,2)>0 && PosNavi(1,1)<=app.Stack.Isize(1) && PosNavi(1,2)<=app.Stack.Isize(2)
                Widget = 'AxesNavi';
                Pos = [PosNavi(1,1), PosNavi(1,2)];
            elseif PosZoom(1,1)>0 && PosZoom(1,2)>0 && PosZoom(1,1)<=app.Stack.Isize(1) && PosZoom(1,2)<=app.Stack.Isize(2)
                Widget = 'AxesZoom';
                Pos = [PosZoom(1,1), PosZoom(1,2)];
            end
        end
        
        function CMap = getCMap(~, CMap)
            % Convert default colormap strings into LUT
            if ischar(CMap)
                switch CMap
                    case 'red'
                        CMap = gray;
                        CMap(:,2) = CMap(:,2)*0;
                        CMap(:,3) = CMap(:,3)*0;
                    case 'green'
                        CMap = gray;
                        CMap(:,1) = CMap(:,1)*0;
                        CMap(:,3) = CMap(:,3)*0;                        
                    case 'blue'
                        CMap = gray;
                        CMap(:,1) = CMap(:,1)*0;
                        CMap(:,2) = CMap(:,2)*0;
                    case 'cyan'
                        CMap = gray;
                        CMap(:,1) = CMap(:,1)*0;
                    case 'yellow'
                        CMap = gray;
                        CMap(:,3) = CMap(:,3)*0;
                    case 'magenta'
                        CMap = gray;
                        CMap(:,2) = CMap(:,2)*0;
                    otherwise
                        CMap = eval(CMap);
                end
            end            
        end
        
        function [RGB, SelObjIDs] = getFrame(app, PosZ, passF, SelectedObjIDs, PosZoom)            
            % User-selected colormaps
            RawCMap = app.getCMap(app.cmbCMapStack.Value);
            RejCMap = app.getCMap(app.cmbCMapRejected.Value);
            SelCMap = app.getCMap(app.cmbCMapSelected.Value);
            ValCMap = app.getCMap(app.cmbCMapValid.Value);

            % Get zoomed region of the image stack
            BW = app.Stack.I(app.ZoomRect(2):app.ZoomRect(2)+app.ZoomRect(4)-1, app.ZoomRect(1):app.ZoomRect(1)+app.ZoomRect(3)-1, PosZ);
            RGB = reshape(RawCMap(BW(:)+1,:),[size(BW),3]);
            SelObjIDs = 0;
            %RGB = ind2rgb(BW, app.getCMap(app.cmbCMapStack.Value));
                        
            % Find borders of the area to zoom according to passed mouse position
            fxmin = app.ZoomRect(1);
            fxmax = app.ZoomRect(1)+app.ZoomRect(3)-1;
            fymin = app.ZoomRect(2);
            fymax = app.ZoomRect(2)+app.ZoomRect(4)-1;

            % Find indeces of objects visible within the zoomed area
%             ValObjIDs = find(passF); % IDs of valid objects within field of view
%             RejObjIDs = find(~passF); % IDs of rejected objects within field of view
            valIcut = passF;
            rejIcut = ~passF;
            for i = 1:numel(valIcut)
                valIcut(i) = valIcut(i) && app.Dots.Pos(i,2)>=fxmin && app.Dots.Pos(i,2)<=fxmax && app.Dots.Pos(i,1)>=fymin && app.Dots.Pos(i,1)<=fymax;
                rejIcut(i) = rejIcut(i) && app.Dots.Pos(i,2)>=fxmin && app.Dots.Pos(i,2)<=fxmax && app.Dots.Pos(i,1)>=fymin && app.Dots.Pos(i,1)<=fymax;
            end
            ValObjIDs = find(valIcut); % IDs of valid objects within field of view  
            RejObjIDs = find(rejIcut); % IDs of rejected objects within field of view 

            % Concatenate objects lists depending on whether they are in columns or rows
            if size(ValObjIDs,1) == 1
                VisObjIDs = [ValObjIDs, RejObjIDs]; % IDs of objects within field of view
            else
                VisObjIDs = [ValObjIDs; RejObjIDs]; % IDs of objects within field of view
            end

            % Flag valid and rejected object IDs within zoomed area
            for i = 1:numel(ValObjIDs)
                VoxPos = app.Dots.Vox(ValObjIDs(i)).Pos(app.Dots.Vox(ValObjIDs(i)).Pos(:,3) == PosZ,:);
                VoxPos = VoxPos(VoxPos(:,2) >= fxmin & VoxPos(:,2) <= fxmax,:);
                VoxPos = VoxPos(VoxPos(:,1) >= fymin & VoxPos(:,1) <= fymax,:);
                if isempty(VoxPos)
                    continue
                end
                NewCols = BW(VoxPos(:,1)-fymin+1,VoxPos(:,2)-fxmin+1);
                NewCols = reshape(ValCMap(NewCols(:)+1,:),[size(NewCols),3]);
                RGB(VoxPos(:,1)-fymin+1,VoxPos(:,2)-fxmin+1, :) = NewCols;
            end
            for i = 1:numel(RejObjIDs)
                VoxPos = app.Dots.Vox(RejObjIDs(i)).Pos(app.Dots.Vox(RejObjIDs(i)).Pos(:,3) == PosZ,:);
                VoxPos = VoxPos(VoxPos(:,2) >= fxmin & VoxPos(:,2) <= fxmax,:);
                VoxPos = VoxPos(VoxPos(:,1) >= fymin & VoxPos(:,1) <= fymax,:);
                if isempty(VoxPos)
                    continue
                end
                NewCols = BW(VoxPos(:,1)-fymin+1,VoxPos(:,2)-fxmin+1);
                NewCols = reshape(RejCMap(NewCols(:)+1,:),[size(NewCols),3]);
                RGB(VoxPos(:,1)-fymin+1,VoxPos(:,2)-fxmin+1, :) = NewCols;
            end

            if ~isempty(SelectedObjIDs) && (numel(SelectedObjIDs)>1 || SelectedObjIDs > 0)
                % If user requested objects within the zoomed region, select them

                SelObjIDs = SelectedObjIDs;
                for i = 1: numel(SelectedObjIDs)
                    VoxPos = app.Dots.Vox(SelectedObjIDs(i)).Pos(app.Dots.Vox(SelectedObjIDs(i)).Pos(:,3) == PosZ,:);
                    VoxPos = VoxPos(VoxPos(:,2) >= fxmin & VoxPos(:,2) <= fxmax,:);
                    VoxPos = VoxPos(VoxPos(:,1) >= fymin & VoxPos(:,1) <= fymax,:);
                    if isempty(VoxPos)
                        continue
                    end                    
                    NewCols = BW(VoxPos(:,1)-fymin+1,VoxPos(:,2)-fxmin+1);
                    NewCols = reshape(SelCMap(NewCols(:)+1,:),[size(NewCols),3]);
                    RGB(VoxPos(:,1)-fymin+1,VoxPos(:,2)-fxmin+1, :) = NewCols;
                    %disp(['SelectedObjID: ' num2str(SelectedObjIDs(i))]);
                end
                
            elseif PosZoom(1) > 0 && PosZoom(2) > 0
                % If user queried for objects at a specific location coordinates
                %disp(['X:' num2str(PosZoom(1)) ' Y:' num2str(PosZoom(2)) ' xmin:' num2str(fxmin) ' ymin:' num2str(fymin)]);
                absX = fxmin+PosZoom(1)-2;
                absY = fymin+PosZoom(2)-2;
                for i = 1:numel(VisObjIDs)
                    VoxPos  = app.Dots.Vox(VisObjIDs(i)).Pos;
                    for j = 1:size(VoxPos,1)
                        if VoxPos(j,3)==PosZ && VoxPos(j,1)==absY && VoxPos(j,2)==absX
                            SelObjIDs = VisObjIDs(i); % Return ID of selected object
                            for k = 1:size(VoxPos,1)
                                if VoxPos(k,3) == PosZ && VoxPos(k,2)>=fxmin && VoxPos(k,2)<=fxmax && VoxPos(k,1)>=fymin && VoxPos(k,1)<=fymax
                                    RGB(VoxPos(k,1)-fymin+1, VoxPos(k,2)-fxmin+1, :) = SelCMap(BW(VoxPos(k,1)-fymin+1, VoxPos(k,2)-fxmin+1)+1,:);
                                end
                            end
                            break
                        end
                    end
                end

            end
        end
        
        function refreshNavi(app)
            % Update rectangle in Navi indicating which part is magnified
            delete(app.ZoomRectHandle);
            app.ZoomRectHandle = rectangle(app.AxesNavi, 'Position', app.ZoomRect,'EdgeColor', [1 1 0],'LineWidth',2,'LineStyle','-');            
        end
        
        function refreshTitleBar(app)
            app.UIFigure.Name = ['Image: ' app.Stack.Name '  Objects: ' app.Dots.Name];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Center the app window on primary monitor
            PosScreen = get(0,'ScreenSize');
            PosUI = app.UIFigure.Position;
            PosUI(1) = ceil(PosScreen(1)+PosScreen(3)/2-PosUI(3)/2);
            PosUI(2) = ceil(PosScreen(2)+PosScreen(4)/2-PosUI(4)/2);
            app.UIFigure.Position = PosUI;
        end

        % Value changed function: SliderZ
        function SliderZValueChanged(app, event)
            app.refreshFrame;       
        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)
            %verticalScrollAmount = event.VerticalScrollAmount            
            PosNew = round(app.SliderZ.Value + event.VerticalScrollCount);
            PosNew = min(PosNew, max(app.SliderZ.Limits));
            PosNew = max(PosNew, min(app.SliderZ.Limits));
            
            app.SliderZ.Value = PosNew;
            app.SliderZValueChanged;
        end

        % Button pushed function: ReloadimagevolumeButton
        function ReloadimagevolumeButtonPushed(app, event)
            dlg = uiprogressdlg(app.UIFigure, 'Title', 'Initializing', 'Message', 'Opening Image Volume...','Indeterminate','on');
            fName = 'RibeyeA.tif';
            [app.Stack.I, app.Stack.ImInfo, app.Stack.MIP, app.Stack.ImRes] = loadImage(fName);
            app.Stack.Isize = size(app.Stack.I);
            app.Stack.Name = fName;
            app.SliderZ.Limits = [1 size(app.Stack.I,3)];
            app.SliderZ.Value = round(mean(app.SliderZ.Limits));

            dlg.Message = 'Opening objects...';
            app.Dots = loadObjects('9ef05580-53ae-4267-b58f-4f6e706c0a0b');
            app.Filter = app.Dots.Filter;
            
            app.initializeImageStack;                        
            app.refreshNavi;
            app.refreshTitleBar;

            close(dlg);
        end

        % Button pushed function: btnZoomIn
        function btnZoomInButtonPushed(app, event)
            app.ZoomRect = [max(1,app.ZoomRect(1)+round(app.ZoomRect(3)/4)),...
                            max(1,app.ZoomRect(2)+round(app.ZoomRect(4)/4)),...
                            max(round(app.ZoomRect(3)/2), 32),...
                            max(round(app.ZoomRect(4)/2), 32)];            
            
            app.refreshFrame;
            app.refreshNavi;           
        end

        % Button pushed function: btnZoomFit
        function btnZoomFitButtonPushed(app, event)
            app.ZoomRect = [1 1 app.Stack.Isize(1) app.Stack.Isize(2)];
            app.refreshFrame; 
            app.refreshNavi;
        end

        % Button pushed function: btnZoomOut
        function btnZoomOutButtonPushed(app, event)
            Xnew = max(1,app.ZoomRect(1)-round(app.ZoomRect(3))/2);
            Xnew = min(Xnew, app.Stack.Isize(1)-app.ZoomRect(3)*2+1);
            
            Ynew = max(1,app.ZoomRect(2)-round(app.ZoomRect(4))/2);
            Ynew = min(Ynew, app.Stack.Isize(2)-app.ZoomRect(4)*2+1);

            
            app.ZoomRect = [Xnew,Ynew,...
                            min(app.ZoomRect(3)*2, app.Stack.Isize(1)),...
                            min(app.ZoomRect(4)*2, app.Stack.Isize(2))];
            app.refreshFrame;        
            app.refreshNavi;
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            % Detect where mouse click happened
            [Widget, PosMouse] = app.whereIsMouse;

            switch Widget
                case 'AxesNavi' % Center magnified area on mouse location                
                    Xnew = max(1,PosMouse(1)-app.ZoomRect(3)/2);
                    Xnew = min(Xnew, app.Stack.Isize(1)-app.ZoomRect(3)+1);
                    app.ZoomRect(1) = round(Xnew);
                    
                    Ynew = max(1,PosMouse(2)-app.ZoomRect(4)/2);
                    Ynew = min(Ynew, app.Stack.Isize(2)-app.ZoomRect(4)+1);
                    app.ZoomRect(2) = round(Ynew);

                    % Redraw magnified region rectangle
                    app.refreshFrame;
                    app.refreshNavi;
                    
                case 'AxesZoom' % Center magnified area on mouse location
                    PosZoom = round(PosMouse);
                    PosZ = round(app.SliderZ.Value);
                    [RGB, app.SelObjIDs] = app.getFrame(PosZ, app.Filter.passF, app.SelObjIDs, PosZoom);
                    image(app.AxesZoom, RGB);
            end
        end

        % Value changed function: cmbCMapRejected, cmbCMapSelected, 
        % ...and 2 other components
        function cmbCMapStackValueChanged(app, event)
            app.refreshFrame;
        end

        % Value changed function: cmbNaviStyle
        function cmbNaviStyleValueChanged(app, event)
            switch app.cmbNaviStyle.Value
                case 'MIP'
                    image(app.AxesNavi, app.Stack.MIP);
                    colormap(app.AxesNavi, 'gray');
                case 'Slice'
                    image(app.AxesNavi, app.Stack.I(:,:,round(app.SliderZ.Value)));
                    colormap(app.AxesNavi, 'gray');
            end
            xlim(app.AxesNavi, [1 size(app.Stack.I,1)]);
            ylim(app.AxesNavi, [1 size(app.Stack.I,2)]); 
            app.refreshNavi;
        end

        % Button pushed function: btnMoveCenter
        function btnMoveCenterButtonPushed(app, event)
            app.ZoomRect(1) = max(1,app.Stack.Isize(1)/2-app.ZoomRect(3)/2);
            app.ZoomRect(2) = max(1,app.Stack.Isize(1)/2-app.ZoomRect(4)/2);
            app.refreshNavi;
            app.refreshFrame;
        end

        % Button pushed function: btnMoveRight
        function btnMoveRightButtonPushed(app, event)
            app.ZoomRect(1) = min(app.Stack.Isize(1)-app.ZoomRect(3)-1, app.ZoomRect(1)+app.ZoomRect(3)/4);
            app.refreshNavi;
            app.refreshFrame;            
        end

        % Button pushed function: btnLeft
        function btnLeftButtonPushed(app, event)
            app.ZoomRect(1) = max(1, app.ZoomRect(1)-app.ZoomRect(3)/4);
            app.refreshNavi;
            app.refreshFrame;                        
        end

        % Button pushed function: btnMoveUp
        function btnMoveUpButtonPushed(app, event)
            app.ZoomRect(2) = max(1, app.ZoomRect(2)-app.ZoomRect(4)/4);
            app.refreshNavi;
            app.refreshFrame;            
        end

        % Button pushed function: btnMoveDown
        function btnMoveDownButtonPushed(app, event)
            app.ZoomRect(2) = min(app.Stack.Isize(2)-app.ZoomRect(4)-1, app.ZoomRect(2)+app.ZoomRect(4)/4);
            app.refreshNavi;
            app.refreshFrame;                
        end

        % Callback function
        function ReloadObjectsButtonPushed(app, event)
            app.Dots = loadObjects('9ef05580-53ae-4267-b58f-4f6e706c0a0b');
            app.Filter = app.Dots.Filter;
        end

        % Button pushed function: btnValidate
        function btnValidateButtonPushed(app, event)
            if numel(app.SelObjIDs) > 1
                % Multiple objects selected
                for i = 1: numel(app.SelObjIDs)
                    app.Filter.passF(app.SelObjIDs(i)) = true;
                end
                app.SelObjID = 0;            % Clear selection
                app.refreshFrame;

            elseif app.SelObjIDs > 0
                % Only one object selected
                app.Filter.passF(app.SelObjIDs) = true;
                %set(txtValidObjs,'string',['Valid: ' num2str(numel(find(Filter.passF)))]);
                app.SelObjIDs = 0;            % Clear selection
                app.refreshFrame;
            end
        end

        % Button pushed function: btnToggleValidation
        function btnToggleValidationButtonPushed(app, event)
            if numel(app.SelObjIDs) > 1
                % Multiple objects selected
                for i = 1: numel(app.SelObjIDs)
                    app.Filter.passF(app.SelObjIDs(i)) = ~app.Filter.passF(app.SelObjIDs(i));
                end
                app.SelObjID = 0;
                app.refreshFrame;

            elseif app.SelObjIDs > 0
                % Only one object selected
                app.Filter.passF(app.SelObjIDs) = ~app.Filter.passF(app.SelObjIDs);
                app.SelObjIDs = 0;
                app.refreshFrame;
            end
        end

        % Button pushed function: btnReject
        function btnRejectButtonPushed(app, event)
            if numel(app.SelObjIDs) > 1
                % Multiple objects selected
                for i = 1: numel(app.SelObjIDs)
                    app.Filter.passF(app.SelObjIDs(i)) = false;
                end
                app.SelObjID = 0;            % Clear selection
                app.refreshFrame;

            elseif app.SelObjIDs > 0
                % Only one object selected
                app.Filter.passF(app.SelObjIDs) = false;
                %set(txtValidObjs,'string',['Valid: ' num2str(numel(find(Filter.passF)))]);
                app.SelObjIDs = 0;            % Clear selection
                app.refreshFrame;
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.AxesZoom = uiaxes(app.GridLayout);
            app.AxesZoom.XTick = [];
            app.AxesZoom.YTick = [];
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '';
            app.SliderZ = uislider(app.GridLayout);
            app.SliderZ.MajorTicks = [];
            app.SliderZ.ValueChangedFcn = createCallbackFcn(app, @SliderZValueChanged, true);
            app.SliderZ.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60 61 62 63 64 65 66 67 68 69 70 71 72 73 74 75 76 77 78 79 80 81 82 83 84 85 86 87 88 89 90 91 92 93 94 95 96 97 98 99 100];
            app.pnlTools = uipanel(app.GridLayout);
            app.pnlTools.Title = 'Objects';
            app.ReloadimagevolumeButton = uibutton(app.pnlTools, 'push');
            app.ReloadimagevolumeButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadimagevolumeButtonPushed, true);
            app.ReloadimagevolumeButton.Text = 'Reload image volume';
            app.btnValidate = uibutton(app.pnlTools, 'push');
            app.btnValidate.ButtonPushedFcn = createCallbackFcn(app, @btnValidateButtonPushed, true);
            app.btnValidate.Icon = '48px-validate.png';
            app.btnValidate.Text = '';
            app.btnReject = uibutton(app.pnlTools, 'push');
            app.btnReject.ButtonPushedFcn = createCallbackFcn(app, @btnRejectButtonPushed, true);
            app.btnReject.Icon = '48px-reject.png';
            app.btnReject.Text = '';
            app.btnToggleValidation = uibutton(app.pnlTools, 'push');
            app.btnToggleValidation.ButtonPushedFcn = createCallbackFcn(app, @btnToggleValidationButtonPushed, true);
            app.btnToggleValidation.Icon = '48px-View-refresh.png';
            app.btnToggleValidation.Text = '';
            app.FilterLabel = uilabel(app.pnlTools);
            app.FilterLabel.Text = 'Filter';
            app.cmbFilterType = uidropdown(app.pnlTools);
            app.cmbFilterType.Items = {'Disabled', 'Score', 'Volume', 'Brightness', 'Roundness', 'Major Axis Length', 'Z positin', 'bone', 'copper', 'pink', 'turbo', 'hsv', 'parula', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'jet'};
            app.cmbFilterType.Value = 'Disabled';
            app.ThresholdLabel = uilabel(app.pnlTools);
            app.ThresholdLabel.Text = 'Threshold';
            app.ThresholdSpinner = uispinner(app.pnlTools);
            app.UITable = uitable(app.pnlTools);
            app.UITable.ColumnName = {'Filter'; 'Threshold'};
            app.UITable.RowName = {};
            app.btnFilterAdd = uibutton(app.pnlTools, 'push');
            app.btnFilterAdd.Icon = '48px-List-add.png';
            app.btnFilterAdd.Text = '';
            app.btnFilterRemove = uibutton(app.pnlTools, 'push');
            app.btnFilterRemove.Icon = '48px-List-remove.png';
            app.btnFilterRemove.Text = '';
            app.btnClear = uibutton(app.pnlTools, 'push');
            app.btnClear.Icon = '48px-Edit-clear.png';
            app.btnClear.Text = '';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.AxesNavi = uiaxes(app.GridLayout2);
            app.AxesNavi.XTick = [];
            app.AxesNavi.YTick = [];
            app.NavigationPanel = uipanel(app.GridLayout2);
            app.NavigationPanel.Title = 'Navigation';
            app.btnZoomIn = uibutton(app.NavigationPanel, 'push');
            app.btnZoomIn.ButtonPushedFcn = createCallbackFcn(app, @btnZoomInButtonPushed, true);
            app.btnZoomIn.Icon = '48px-Zoom-in.png';
            app.btnZoomIn.Text = '';
            app.btnZoomOut = uibutton(app.NavigationPanel, 'push');
            app.btnZoomOut.ButtonPushedFcn = createCallbackFcn(app, @btnZoomOutButtonPushed, true);
            app.btnZoomOut.Icon = '48px-Zoom-out.png';
            app.btnZoomOut.Text = '';
            app.btnZoomFit = uibutton(app.NavigationPanel, 'push');
            app.btnZoomFit.ButtonPushedFcn = createCallbackFcn(app, @btnZoomFitButtonPushed, true);
            app.btnZoomFit.Icon = '48px-Zoom-fit.png';
            app.btnZoomFit.Text = '';
            app.RawimageLabel = uilabel(app.NavigationPanel);
            app.RawimageLabel.Text = 'Raw image';
            app.cmbCMapStack = uidropdown(app.NavigationPanel);
            app.cmbCMapStack.Items = {'gray', 'red', 'green', 'blue', 'cyan', 'yellow', 'magenta', 'bone', 'copper', 'pink', 'turbo', 'hsv', 'parula', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'jet'};
            app.cmbCMapStack.ValueChangedFcn = createCallbackFcn(app, @cmbCMapStackValueChanged, true);
            app.cmbCMapStack.Value = 'gray';
            app.cmbNaviStyle = uiswitch(app.NavigationPanel, 'slider');
            app.cmbNaviStyle.Items = {'MIP', 'Slice'};
            app.cmbNaviStyle.ValueChangedFcn = createCallbackFcn(app, @cmbNaviStyleValueChanged, true);
            app.cmbNaviStyle.Value = 'MIP';
            app.btnMoveCenter = uibutton(app.NavigationPanel, 'push');
            app.btnMoveCenter.ButtonPushedFcn = createCallbackFcn(app, @btnMoveCenterButtonPushed, true);
            app.btnMoveCenter.Icon = '48px-center.png';
            app.btnMoveCenter.Text = '';
            app.btnMoveRight = uibutton(app.NavigationPanel, 'push');
            app.btnMoveRight.ButtonPushedFcn = createCallbackFcn(app, @btnMoveRightButtonPushed, true);
            app.btnMoveRight.Icon = '48px-right.png';
            app.btnMoveRight.Text = '';
            app.btnMoveUp = uibutton(app.NavigationPanel, 'push');
            app.btnMoveUp.ButtonPushedFcn = createCallbackFcn(app, @btnMoveUpButtonPushed, true);
            app.btnMoveUp.Icon = '48px-up.png';
            app.btnMoveUp.Text = '';
            app.btnMoveDown = uibutton(app.NavigationPanel, 'push');
            app.btnMoveDown.ButtonPushedFcn = createCallbackFcn(app, @btnMoveDownButtonPushed, true);
            app.btnMoveDown.Icon = '48px-down.png';
            app.btnMoveDown.Text = '';
            app.btnLeft = uibutton(app.NavigationPanel, 'push');
            app.btnLeft.ButtonPushedFcn = createCallbackFcn(app, @btnLeftButtonPushed, true);
            app.btnLeft.Icon = '48px-left.png';
            app.btnLeft.Text = '';
            app.ValidobjectsLabel = uilabel(app.NavigationPanel);
            app.ValidobjectsLabel.Text = 'Valid objects:';
            app.cmbCMapValid = uidropdown(app.NavigationPanel);
            app.cmbCMapValid.Items = {'gray', 'red', 'green', 'blue', 'cyan', 'yellow', 'magenta', 'bone', 'copper', 'pink', 'turbo', 'hsv', 'parula', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'jet'};
            app.cmbCMapValid.ValueChangedFcn = createCallbackFcn(app, @cmbCMapStackValueChanged, true);
            app.cmbCMapValid.Value = 'green';
            app.RejectedobjectsLabel = uilabel(app.NavigationPanel);
            app.RejectedobjectsLabel.Text = 'Rejected objects:';
            app.cmbCMapRejected = uidropdown(app.NavigationPanel);
            app.cmbCMapRejected.Items = {'gray', 'red', 'green', 'blue', 'cyan', 'yellow', 'magenta', 'bone', 'copper', 'pink', 'turbo', 'hsv', 'parula', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'jet'};
            app.cmbCMapRejected.ValueChangedFcn = createCallbackFcn(app, @cmbCMapStackValueChanged, true);
            app.cmbCMapRejected.Value = 'magenta';
            app.SelectedobjectsLabel = uilabel(app.NavigationPanel);
            app.SelectedobjectsLabel.Text = 'Selected objects:';
            app.cmbCMapSelected = uidropdown(app.NavigationPanel);
            app.cmbCMapSelected.Items = {'gray', 'red', 'green', 'blue', 'cyan', 'yellow', 'magenta', 'bone', 'copper', 'pink', 'turbo', 'hsv', 'parula', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'jet'};
            app.cmbCMapSelected.ValueChangedFcn = createCallbackFcn(app, @cmbCMapStackValueChanged, true);
            app.cmbCMapSelected.Value = 'yellow';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SlicedVolumeInspector

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end